classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ImplementacindesensoresenMaxArmLabel  matlab.ui.control.Label
        ColordeobjetoLamp           matlab.ui.control.Lamp
        ColordeobjetoLampLabel      matlab.ui.control.Label
        obtenerDatoButton           matlab.ui.control.Button
        SensorValueTextArea         matlab.ui.control.TextArea
        MonitorLabel                matlab.ui.control.Label
        ConexindelDispositivoPanel  matlab.ui.container.Panel
        ConnectionLamp              matlab.ui.control.Lamp
        ConnectButton               matlab.ui.control.Button
        SensorDropDown              matlab.ui.control.DropDown
        SensorLabel                 matlab.ui.control.Label
        DataAxes                    matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Properties that are private
    properties (Access = private)
        tcpClient        % Cliente TCP
        KeepAliveTimer   % Temporizador para mantener la conexión
        hPlot            % Referencia al gráfico de datos
        isConnected      % Estado de la conexión
    end
    methods (Access = private)

        function colorName = getColorName(~, colorCode)
            switch colorCode
                case 1
                    colorName = 'Rojo';
                case 2
                    colorName = 'Verde';
                case 3
                    colorName = 'Azul';
                otherwise
                    colorName = 'Desconocido';
            end
        end
        function updatePlot(app, newValue)
            % Asegurarse de que la gráfica esté inicializada
            if isempty(app.hPlot)
                app.hPlot = plot(app.DataAxes, NaN, NaN, '-o', 'LineWidth', 2);
                hold(app.DataAxes, 'on');
            end

            % Obtener el sensor seleccionado
            selectedSensor = app.SensorDropDown.Value;

            % Configuración dinámica según el sensor seleccionado
            switch selectedSensor
                case 'Distancia'
                    ylim(app.DataAxes, [0 100]); % Límite para distancia
                    ylabel(app.DataAxes, 'Distancia (mm)');
                    title(app.DataAxes, 'Distancia (mm)');

                case 'Sonido'
                    ylim(app.DataAxes, [0 100]); % Límite para sonido
                    ylabel(app.DataAxes, 'Sonido (%)');
                    title(app.DataAxes, 'Nivel de Sonido');

                case 'Luz'
                    ylim(app.DataAxes, [0 100]); % Límite para luz
                    ylabel(app.DataAxes, 'Luz (%)');
                    title(app.DataAxes, 'Nivel de Luz');

                case 'Touch'
                    ylim(app.DataAxes, [0 1]); % Binario para touch
                    ylabel(app.DataAxes, 'Estado');
                    title(app.DataAxes, 'Estado del Sensor Táctil');

                case 'Color'
                    ylim(app.DataAxes, [0 4]); % Límite para color
                    yticks(app.DataAxes, [1 2 3]); % Valores discretos
                    yticklabels(app.DataAxes, {'Rojo', 'Azul', 'Verde'});
                    ylabel(app.DataAxes, 'Color');
                    title(app.DataAxes, 'Color Detectado');
            end

            % Obtener los datos actuales
            xData = app.hPlot.XData;
            yData = app.hPlot.YData;

            % Generar un nuevo índice para el eje X
            if isempty(xData) || all(isnan(xData))
                newX = 1; % Empezar desde 1
            else
                newX = xData(end) + 1; % Incrementar el índice
            end

            % Agregar los nuevos datos
            xData = [xData, newX];
            yData = [yData, newValue];

            % Limitar la gráfica a las últimas 10 muestras
            if numel(xData) > 10
                xData = xData(end-9:end);
                yData = yData(end-9:end);
            end

            % Actualizar la gráfica
            set(app.hPlot, 'XData', xData, 'YData', yData);
            xlim(app.DataAxes, [min(xData) max(xData)]);
            drawnow;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ConnectionLamp.Color = 'red';
            app.ConnectButton.Text = 'Conectar';

            % Crear un temporizador para mantener la conexión activa
            app.KeepAliveTimer = timer('ExecutionMode', 'fixedRate', ...
                'Period', 5, ... % Cada 5 segundos
                'TimerFcn', @(~,~) keepAlive(app));
            % Inicializar la gráfica con un placeholder vacío
            hold(app.DataAxes, 'on');
            app.hPlot = plot(app.DataAxes, NaN, NaN, 'LineWidth', 2); % Placeholder para los datos
            title(app.DataAxes, 'Gráfica de Datos del Sensor');
            xlabel(app.DataAxes, 'Número de muestra');
            ylabel(app.DataAxes, 'Valor del Sensor');
        end

        % Función para mantener la conexión activa
        function keepAlive(app)
            if ~isempty(app.tcpClient)
                % Enviar un mensaje de mantenimiento
                try
                    write(app.tcpClient, "PING\n");
                    disp('Enviando PING para mantener la conexión.');
                catch
                    disp('Error al enviar PING.');
                end
            end
        end

        % Función para cambiar el color de la lámpara
        function setColorLamp(app, colorCode)
            switch colorCode
                case 1 % Rojo
                    app.ColordeobjetoLamp.Color = [1, 0, 0]; % Rojo
                case 2 % Verde
                    app.ColordeobjetoLamp.Color = [0, 1, 0]; % Verde
                case 3 % Azul
                    app.ColordeobjetoLamp.Color = [0, 0, 1]; % Azul
                otherwise
                    app.ColordeobjetoLamp.Color = [1, 1, 1]; % Blanco
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            if app.isConnected
                % Desconectar
                app.isConnected = false;
                if ~isempty(app.tcpClient) && isvalid(app.tcpClient)
                    clear app.tcpClient;
                end
                % Actualizar la lámpara y el texto del botón
                app.ConnectionLamp.Color = 'red';
                app.ConnectButton.Text = 'Conectar';
                disp('Desconectado del ESP32.');
            else
                % Conectar
                try
                    % Intenta crear una nueva conexión TCP
                    app.tcpClient = tcpclient('192.168.7.239', 8888);
                    disp('Conectado al ESP32.');

                    % Actualizar la lámpara y el texto del botón
                    app.ConnectionLamp.Color = 'green';
                    app.ConnectButton.Text = 'Desconectar';
                    app.isConnected = true;
                catch
                    % En caso de error al conectar
                    disp('Error al conectar al ESP32. Verifica la IP y el puerto.');
                    return;
                end

                % Inicializar la gráfica
                hold(app.DataAxes, 'on');
                app.hPlot = plot(app.DataAxes, NaN, NaN); % Placeholder para la gráfica

                while app.isConnected
                    % Verifica si el cliente TCP sigue siendo válido y está conectado
                    if isempty(app.tcpClient) || ~isvalid(app.tcpClient)
                        disp('Cliente TCP no válido o eliminado.');
                        % Actualizar la lámpara y el texto del botón
                        app.ConnectionLamp.Color = 'red';
                        app.ConnectButton.Text = 'Conectar';
                        app.isConnected = false;
                        break; % Salir del bucle si el cliente se desconecta
                    end

                    pause(1); % Esperar un segundo antes de la siguiente lectura
                end
            end
        end

        % Value changed function: SensorDropDown
        function SensorDropDownValueChanged(app, event)

            selectedSensor = app.SensorDropDown.Value;

            % Cambiar el título y configuración de la gráfica dependiendo del sensor seleccionado
            switch selectedSensor
                case 'Distancia'
                    title(app.DataAxes, 'Distancia (mm)');
                    ylim(app.DataAxes, [0 500]); % Ajusta el límite del eje Y para distancia
                    ylabel(app.DataAxes, 'Distancia (mm)');
                    cla(app.DataAxes); % Limpiar la gráfica
                    app.hPlot = plot(app.DataAxes, NaN, NaN, '-o'); % Reiniciar la gráfica

                case 'Sonido'
                    title(app.DataAxes, 'Nivel de Sonido');
                    ylim(app.DataAxes, [0 100]); % Ajusta el límite del eje Y para sonido (0-100%)
                    ylabel(app.DataAxes, 'Sonido (%)');
                    cla(app.DataAxes);
                    app.hPlot = plot(app.DataAxes, NaN, NaN, '-o');

                case 'Luz'
                    title(app.DataAxes, 'Nivel de Luz');
                    ylim(app.DataAxes, [0 100]); % Ajusta el límite del eje Y para luz (0-100%)
                    ylabel(app.DataAxes, 'Luz (%)');
                    cla(app.DataAxes);
                    app.hPlot = plot(app.DataAxes, NaN, NaN, '-o');

                case 'Touch'
                    title(app.DataAxes, 'Estado del Sensor Táctil');
                    ylim(app.DataAxes, [0 1]); % Sensor táctil es binario (0 o 1)
                    ylabel(app.DataAxes, 'Estado');
                    cla(app.DataAxes);
                    app.hPlot = plot(app.DataAxes, NaN, NaN, '-o');

                case 'Color'
                    title(app.DataAxes, 'Color Detectado');
                    ylim(app.DataAxes, [0 4]); % Limitar Y entre 0 y 4 para los colores
                    yticks(app.DataAxes, [1 2 3]); % Etiquetas para los colores
                    yticklabels(app.DataAxes, {'Rojo', 'Azul', 'Verde'}); % Nombres de colores
                    ylabel(app.DataAxes, 'Color');
                    cla(app.DataAxes);
                    app.hPlot = plot(app.DataAxes, NaN, NaN, 'o');

                otherwise
                    title(app.DataAxes, 'Selecciona un sensor');
                    cla(app.DataAxes);
                    app.hPlot = plot(app.DataAxes, NaN, NaN, '-o');
            end


        end

        % Button pushed function: obtenerDatoButton
        function obtenerDatoButtonPushed(app, event)


                if isempty(app.tcpClient) || ~isvalid(app.tcpClient)
                    disp('No hay conexión con el ESP32.');
                    return;
                end

                try
                    % Limpia el buffer antes de enviar el comando
                    flush(app.tcpClient);

                    % Obtener el sensor seleccionado
                    selectedSensor = app.SensorDropDown.Value;

                    % Enviar el comando al ESP32
                    command = [selectedSensor '\n'];
                    write(app.tcpClient, command);
                    disp(['Comando enviado al ESP32: ', command]);

                    % Esperar hasta que haya datos disponibles
                    timeout = 5; % Máximo de 5 segundos para la respuesta
                    startTime = tic;
                    while app.tcpClient.NumBytesAvailable == 0
                        if toc(startTime) > timeout
                            disp('Timeout al esperar datos del ESP32.');
                            return;
                        end
                        pause(0.1); % Pausa breve para evitar bloquear el sistema
                    end

                    % Leer datos disponibles
                    rawData = readline(app.tcpClient);
                    disp(['Datos recibidos: ', rawData]);

                    % Validar y decodificar JSON
                    try
                        jsonData = jsondecode(rawData);
                    catch
                        disp('Error al decodificar los datos recibidos.');
                        return;
                    end

                    % Procesar el dato recibido según el sensor seleccionado
                    switch selectedSensor
                        case 'Distancia'
                            valorCalibrado = jsonData.calibratedValue;
                            app.SensorValueTextArea.Value = ['Valor Distancia: ', num2str(valorCalibrado)];
                            updatePlot(app, valorCalibrado);
                        case 'Sonido'
                            soundPercentage = jsonData.soundValue; % Dato enviado como porcentaje
                            app.SensorValueTextArea.Value = ['Nivel de Sonido: ', num2str(soundPercentage), '%'];
                            updatePlot(app, soundPercentage);
                        case 'Luz'
                            % Leer el porcentaje de luz enviado desde Arduino
                            lightPercentage = jsonData.lightValue; % Dato enviado como porcentaje
                            app.SensorValueTextArea.Value = ['Nivel de Luz: ', num2str(lightPercentage), '%'];

                            % Actualizar la gráfica con el porcentaje de luz
                            updatePlot(app, lightPercentage);
                        case 'Touch'
                            if isfield(jsonData, 'touchState')
                                touchState = jsonData.touchState; % Leer estado del sensor táctil
                                app.SensorValueTextArea.Value = ['Estado del sensor táctil: ', touchState];
                                disp(['Estado del sensor táctil: ', touchState]);
                            else
                                disp('El campo touchStatus no existe en el JSON recibido.');
                                app.SensorValueTextArea.Value = 'Error: touchStatus no encontrado.';
                            end
                        case 'Color'
                            colorDetected = jsonData.colorDetected;
                            colorName = getColorName(app, colorDetected);
                            app.SensorValueTextArea.Value = ['Color Detectado: ', colorName];
                            setColorLamp(app, colorDetected);
                            % Actualizar la gráfica para el sensor de color
                            updatePlot(app, colorDetected);
                            
                        otherwise
                            disp('Sensor no reconocido.');
                    end
                catch ME
                    disp('Error al leer o procesar datos.');
                    disp(getReport(ME));
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataAxes = uiaxes(app.UIFigure);
            title(app.DataAxes, 'Gráfica')
            xlabel(app.DataAxes, 'X')
            ylabel(app.DataAxes, 'Y')
            zlabel(app.DataAxes, 'Z')
            app.SensorLabel = uilabel(app.UIFigure);
            app.SensorLabel.Text = 'Sensor';
            app.SensorDropDown = uidropdown(app.UIFigure);
            app.SensorDropDown.Items = {'', 'Distancia', 'Sonido', 'Luz', 'Touch', 'Color'};
            app.SensorDropDown.ValueChangedFcn = createCallbackFcn(app, @SensorDropDownValueChanged, true);
            app.SensorDropDown.Value = 'Distancia';
            app.ConexindelDispositivoPanel = uipanel(app.UIFigure);
            app.ConexindelDispositivoPanel.Title = 'Conexión del Dispositivo';
            app.ConnectButton = uibutton(app.ConexindelDispositivoPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectionLamp = uilamp(app.ConexindelDispositivoPanel);
            app.MonitorLabel = uilabel(app.UIFigure);
            app.MonitorLabel.Text = 'Monitor';
            app.SensorValueTextArea = uitextarea(app.UIFigure);
            app.obtenerDatoButton = uibutton(app.UIFigure, 'push');
            app.obtenerDatoButton.ButtonPushedFcn = createCallbackFcn(app, @obtenerDatoButtonPushed, true);
            app.obtenerDatoButton.Text = 'Obtener Dato';
            app.ColordeobjetoLampLabel = uilabel(app.UIFigure);
            app.ColordeobjetoLampLabel.Text = 'Color de objeto';
            app.ColordeobjetoLamp = uilamp(app.UIFigure);
            app.ImplementacindesensoresenMaxArmLabel = uilabel(app.UIFigure);
            app.ImplementacindesensoresenMaxArmLabel.Text = 'Implementación de sensores en MaxArm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end